classdef robot_arm_sim_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        Panel                          matlab.ui.container.Panel
        DesiredPositionLabel           matlab.ui.control.Label
        XSpinnerLabel                  matlab.ui.control.Label
        XSpinner                       matlab.ui.control.Spinner
        YSpinnerLabel                  matlab.ui.control.Label
        YSpinner                       matlab.ui.control.Spinner
        ZSpinnerLabel                  matlab.ui.control.Label
        ZSpinner                       matlab.ui.control.Spinner
        Panel_2                        matlab.ui.container.Panel
        CurrentObstacleMapLabel        matlab.ui.control.Label
        Label                          matlab.ui.control.Label
        MapDropDown                    matlab.ui.control.DropDown
        reopenrefreshplotwindowButton  matlab.ui.control.Button
        Panel_3                        matlab.ui.container.Panel
        SetDestinationandMapButton     matlab.ui.control.Button
        Panel_4                        matlab.ui.container.Panel
        LynxArmmovementsimulatorLabel  matlab.ui.control.Label
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            global currentmap 
            global currentconf 
            global currentpath
            global currentmapname
            
            currentconf = [0 0 0 0];
            currentpath = [];
            
            lynxStart();
            currentmapname = app.MapDropDown.Value;
            fileSelected = currentmapname;
            fileSelected = strcat(fileSelected,".txt");
            
            currentmap = loadmap(fileSelected);
            plotmap(currentmap);
        end

        % Button pushed function: SetDestinationandMapButton
        function SetDestinationandMapButtonPushed(app, event)

            global currentmap 
            global currentconf
            global currentpath
            global currentmapname
            
            if ~isempty(currentpath) 
                deletePointsFromPlot(currentpath)
            end
            fileSelected = app.MapDropDown.Value;
            
            if ~strcmp(currentmapname, fileSelected)
                currentmapname = fileSelected;
                fileSelected = strcat(fileSelected,".txt");
                currentmap = loadmap(fileSelected);
                
                lynxStart();
                plotmap(currentmap);
                plotLynx([currentconf 0]);
            end
            goal = [app.XSpinner.Value  app.YSpinner.Value  app.ZSpinner.Value];
            path = findpath(currentmap, currentconf, goal);
            if ~isstring(path)
                lastConfig = path(size(path,1),:);
                currentconf = lastConfig;
                currentpath = showPath(path);
            else
                currentconf = [0 0 0 0];
                plotLynx([currentconf 0]);
                errordlg(path)
            end
            

        end

        % Button pushed function: reopenrefreshplotwindowButton
        function reopenrefreshplotwindowButtonPushed(app, event)
            startupFcn(app);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.Panel = uipanel(app.UIFigure);
            app.DesiredPositionLabel = uilabel(app.Panel);
            app.DesiredPositionLabel.Text = 'Desired Position';
            app.XSpinnerLabel = uilabel(app.Panel);
            app.XSpinnerLabel.Text = 'X';
            app.XSpinner = uispinner(app.Panel);
            app.XSpinner.Limits = [-400 400];
            app.XSpinner.ValueDisplayFormat = '%.2f';
            app.XSpinner.Value = 292.1;
            app.YSpinnerLabel = uilabel(app.Panel);
            app.YSpinnerLabel.Text = 'Y';
            app.YSpinner = uispinner(app.Panel);
            app.YSpinner.Limits = [-400 400];
            app.YSpinner.ValueDisplayFormat = '%.2f';
            app.ZSpinnerLabel = uilabel(app.Panel);
            app.ZSpinnerLabel.Text = 'Z';
            app.ZSpinner = uispinner(app.Panel);
            app.ZSpinner.Limits = [-300 500];
            app.ZSpinner.ValueDisplayFormat = '%.2f';
            app.ZSpinner.Value = 222.25;
            app.Panel_2 = uipanel(app.UIFigure);
            app.CurrentObstacleMapLabel = uilabel(app.Panel_2);
            app.CurrentObstacleMapLabel.Text = 'Current Obstacle Map';
            app.Label = uilabel(app.Panel_2);
            app.Label.Text = '';
            app.MapDropDown = uidropdown(app.Panel_2);
            app.MapDropDown.Items = {'map_1', 'map_2', 'map_3', 'map_4'};
            app.MapDropDown.Value = 'map_1';
            app.reopenrefreshplotwindowButton = uibutton(app.Panel_2, 'push');
            app.reopenrefreshplotwindowButton.ButtonPushedFcn = createCallbackFcn(app, @reopenrefreshplotwindowButtonPushed, true);
            app.reopenrefreshplotwindowButton.Text = 'reopen/refresh plot window';
            app.Panel_3 = uipanel(app.UIFigure);
            app.SetDestinationandMapButton = uibutton(app.Panel_3, 'push');
            app.SetDestinationandMapButton.ButtonPushedFcn = createCallbackFcn(app, @SetDestinationandMapButtonPushed, true);
            app.SetDestinationandMapButton.Text = 'Set Destination and Map';
            app.Panel_4 = uipanel(app.UIFigure);
            app.LynxArmmovementsimulatorLabel = uilabel(app.Panel_4);
            app.LynxArmmovementsimulatorLabel.Text = 'Lynx Arm movement simulator';
        end
    end

    methods (Access = public)

        % Construct app
        function app = robot_arm_sim_app

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end